classdef Voyager_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Main_UI                 matlab.ui.Figure
        MHzLabel                matlab.ui.control.Label
        CurrenttimeLabel_4      matlab.ui.control.Label
        Fs                      matlab.ui.control.NumericEditField
        Gamma_correction        matlab.ui.control.Slider
        BrighnessSlider_2Label  matlab.ui.control.Label
        Edges_enhancement       matlab.ui.control.Slider
        ContrastSliderLabel     matlab.ui.control.Label
        PlayButton              matlab.ui.control.StateButton
        SecondsLabel            matlab.ui.control.Label
        Current_frame_field     matlab.ui.control.Spinner
        CurrenttimeLabel_3      matlab.ui.control.Label
        FramesSlider            matlab.ui.control.Slider
        FrameselectionLabel     matlab.ui.control.Label
        Step_size_field         matlab.ui.control.NumericEditField
        CurrenttimeLabel_2      matlab.ui.control.Label
        Title                   matlab.ui.control.Label
        Channel_selection       matlab.ui.container.ButtonGroup
        Channel_2_button        matlab.ui.control.ToggleButton
        Channel_1_button        matlab.ui.control.ToggleButton
        Image_figure            matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Selected_chennel
        Channel_1_audio
        Channel_2_audio
        Channel_1_audio_temp
        Channel_2_audio_temp
        Voyager_audio_Fs
        initial_Fs

        Step_size
        Current_frame
        
        Sharpening_filter

    end

    methods (Access = private)

        function Update_image(app)
            ratio = app.Fs.Value/app.initial_Fs;
            Reduced_block_size =375808;
            %(375808-mod(375808,(734-floor(734*(ratio-1)))));

%             Shear_mat = [1 0 0; 0 1 0; 0 0 1];
            if fix(app.Current_frame)+1-floor(734*(ratio-1))>=0
                Current_window = reshape(app.Selected_chennel(fix(app.Current_frame)+1:fix(app.Current_frame)+Reduced_block_size),[],512);
%                 Current_window = [Current_window(734-floor(734*(ratio-1)):end,:);Current_window(1:734-floor(734*(ratio-1)),:)];
            else
                Current_window = reshape(app.Selected_chennel(fix(app.Current_frame)+1:fix(app.Current_frame)+Block_size),[],512);
            end
            Current_window = Current_window - imfilter(Current_window,app.Sharpening_filter);
            Current_window = imadjust(Current_window,[],[],app.Gamma_correction.Value);
            imshow(Current_window,'Parent',app.Image_figure)
        end
        
        function Resample_audio(app)
            %app.Channel_1_audio_temp = resample(app.Channel_1_audio,app.Voyager_audio_Fs,app.Fs.Value);
            %app.Channel_2_audio_temp = resample(app.Channel_2_audio,app.Voyager_audio_Fs,app.Fs.Value);
            %Change_channel(app, nan)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             Read audio signal and devide into channels:
            [Voyager_audio,app.Voyager_audio_Fs] = audioread('voyager.mp3');
            app.initial_Fs = app.Voyager_audio_Fs;
            app.Channel_1_audio = Voyager_audio(:,1);
            app.Channel_2_audio = Voyager_audio(:,2);
            app.Channel_1_audio_temp = app.Channel_1_audio(735:end);
            app.Channel_2_audio_temp = app.Channel_2_audio(735:end);
            app.Selected_chennel = app.Channel_1_audio;

            %             Initialize parameters and figure:
            app.Step_size = 5000;
            app.Step_size_field.Value = app.Step_size;

            app.Current_frame = 0;
            app.Current_frame_field.Value = app.Current_frame;
            app.Current_frame_field.Step = app.Step_size/app.Voyager_audio_Fs;
            app.Fs.Value = app.Voyager_audio_Fs;


            %           Populate figure with startup sliding window:
            app.Update_image

            %             Define Slider limits:
            app.FramesSlider.Limits = [1,(length(Voyager_audio(:,1))-375808+1)/app.Step_size];

            
            app.Sharpening_filter = [0,-app.Edges_enhancement.Value,0;...
                         -app.Edges_enhancement.Value,-1+4*app.Edges_enhancement.Value,-app.Edges_enhancement.Value;...
                         0,-app.Edges_enhancement.Value,0];
        end

        % Selection changed function: Channel_selection
        function Change_channel(app, event)
             
            if strcmp(app.Channel_selection.SelectedObject.Text,"Channel 1")
                app.Selected_chennel = app.Channel_1_audio_temp;
            else
                app.Selected_chennel = app.Channel_2_audio_temp;
            end

            app.Update_image;
        end

        % Value changed function: FramesSlider
        function Slider_chenge_value(app, event)
            %             Update image:
            app.Current_frame = app.FramesSlider.Value*app.Step_size*(app.initial_Fs/app.Fs.Value);
            %app.Current_frame = app.Current_frame*(app.Voyager_audio_Fs/app.Fs.Value);
            app.Update_image;

            %             Update time frame:
            app.Current_frame_field.Value = app.Current_frame/app.Fs.Value;
        end

        % Value changed function: Step_size_field
        function Step_size_changed(app, event)
            app.Step_size = app.Step_size_field.Value;

            %             Update slider and spiner:
            app.FramesSlider.Limits = [1,(length(app.Channel_1_audio_temp)-375808+1)/app.Step_size];
            app.Current_frame_field.Step = app.Step_size/app.Voyager_audio_Fs;
        end

        % Value changing function: Current_frame_field
        function Current_frame_change(app, event)
%             Update slider:
            app.FramesSlider.Value = app.Fs.Value*event.Value*(app.Fs.Value/app.initial_Fs)/app.Step_size;
            
%             Update image:
            app.Current_frame = event.Value*app.Fs.Value*(app.Fs.Value/app.initial_Fs);
            app.Update_image;
            
        end

        % Value changed function: PlayButton
        function Play_callback(app, event)
                while app.PlayButton.Value
                    app.Current_frame_field.Value = app.Current_frame_field.Value + app.Current_frame_field.Step;
                    app.Current_frame = app.Fs.Value*app.Current_frame_field.Value*(app.Fs.Value/app.initial_Fs);
                    app.FramesSlider.Value = app.Current_frame/app.Step_size;
                    app.Update_image;
                    pause(0.1)
%                     java.lang.Thread.sleep(1000*1000)
                end

            
        end

        % Value changed function: Edges_enhancement, Gamma_correction
        function Brightness_change(app, event)
            app.Sharpening_filter = [0,-app.Edges_enhancement.Value,0;...
                         -app.Edges_enhancement.Value,-1+4*app.Edges_enhancement.Value,-app.Edges_enhancement.Value;...
                         0,-app.Edges_enhancement.Value,0];
            app.Update_image;
            
        end

        % Value changed function: Fs
        function FsValueChanged(app, event)
            if app.Fs.Value>0
                app.FramesSlider.Limits(2) = app.FramesSlider.Limits(2)*(app.Voyager_audio_Fs/app.Fs.Value);
                app.FramesSlider.Value = floor(app.FramesSlider.Value*(app.Voyager_audio_Fs/app.Fs.Value));
                app.Current_frame_field.Value = app.Current_frame_field.Value*(app.Voyager_audio_Fs/app.Fs.Value);
%                 app.Current_frame = app.Current_frame*(app.Voyager_audio_Fs/app.Fs.Value);
                %app.Resample_audio
                app.Update_image;
                app.Voyager_audio_Fs = app.Fs.Value;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Main_UI = uifigure('Visible', 'off');
            app.Main_UI.Name = 'MATLAB App';
            app.Image_figure = uiaxes(app.Main_UI);
            zlabel(app.Image_figure, 'Z')
            app.Channel_selection = uibuttongroup(app.Main_UI);
            app.Channel_selection.SelectionChangedFcn = createCallbackFcn(app, @Change_channel, true);
            app.Channel_selection.Title = 'Channel selection';
            app.Channel_1_button = uitogglebutton(app.Channel_selection);
            app.Channel_1_button.Text = 'Channel 1';
            app.Channel_1_button.Value = true;
            app.Channel_2_button = uitogglebutton(app.Channel_selection);
            app.Channel_2_button.Text = 'Channel 2';
            app.Title = uilabel(app.Main_UI);
            app.Title.Text = 'Voyager Golden Recod';
            app.CurrenttimeLabel_2 = uilabel(app.Main_UI);
            app.CurrenttimeLabel_2.Text = 'Step size';
            app.Step_size_field = uieditfield(app.Main_UI, 'numeric');
            app.Step_size_field.LowerLimitInclusive = 'off';
            app.Step_size_field.Limits = [0 Inf];
            app.Step_size_field.RoundFractionalValues = 'on';
            app.Step_size_field.ValueChangedFcn = createCallbackFcn(app, @Step_size_changed, true);
            app.Step_size_field.Value = 1;
            app.FrameselectionLabel = uilabel(app.Main_UI);
            app.FrameselectionLabel.Text = 'Frame selection';
            app.FramesSlider = uislider(app.Main_UI);
            app.FramesSlider.MajorTicks = [];
            app.FramesSlider.MajorTickLabels = {''};
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @Slider_chenge_value, true);
            app.FramesSlider.MinorTicks = [];
            app.CurrenttimeLabel_3 = uilabel(app.Main_UI);
            app.CurrenttimeLabel_3.Text = 'Current time';
            app.Current_frame_field = uispinner(app.Main_UI);
            app.Current_frame_field.ValueChangingFcn = createCallbackFcn(app, @Current_frame_change, true);
            app.Current_frame_field.Limits = [0 Inf];
            app.Current_frame_field.ValueDisplayFormat = '%.3f';
            app.SecondsLabel = uilabel(app.Main_UI);
            app.SecondsLabel.Text = 'Seconds';
            app.PlayButton = uibutton(app.Main_UI, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @Play_callback, true);
            app.PlayButton.Icon = 'slrtRunIcon.png';
            app.PlayButton.Text = 'Play';
            app.ContrastSliderLabel = uilabel(app.Main_UI);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.Edges_enhancement = uislider(app.Main_UI);
            app.Edges_enhancement.Limits = [0 2];
            app.Edges_enhancement.MajorTicks = [0 1 2];
            app.Edges_enhancement.MajorTickLabels = {''};
            app.Edges_enhancement.ValueChangedFcn = createCallbackFcn(app, @Brightness_change, true);
            app.Edges_enhancement.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.Edges_enhancement.Value = 1;
            app.BrighnessSlider_2Label = uilabel(app.Main_UI);
            app.BrighnessSlider_2Label.Text = 'Brighness';
            app.Gamma_correction = uislider(app.Main_UI);
            app.Gamma_correction.Limits = [0 1];
            app.Gamma_correction.MajorTicks = [0 0.5 1];
            app.Gamma_correction.MajorTickLabels = {''};
            app.Gamma_correction.ValueChangedFcn = createCallbackFcn(app, @Brightness_change, true);
            app.Gamma_correction.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Gamma_correction.Value = 0.5;
            app.Fs = uieditfield(app.Main_UI, 'numeric');
            app.Fs.LowerLimitInclusive = 'off';
            app.Fs.Limits = [0 Inf];
            app.Fs.ValueChangedFcn = createCallbackFcn(app, @FsValueChanged, true);
            app.Fs.Value = 1;
            app.CurrenttimeLabel_4 = uilabel(app.Main_UI);
            app.CurrenttimeLabel_4.Text = 'Fs';
            app.MHzLabel = uilabel(app.Main_UI);
            app.MHzLabel.Text = 'MHz';
            % Show the figure after all components are created
            app.Main_UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Voyager_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Main_UI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Main_UI)
        end
    end
end